classdef difraccion_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ReddedifraccinTab               matlab.ui.container.Tab
        TablaDatos_2                    matlab.ui.control.Table
        Lo_2                            matlab.ui.control.NumericEditField
        LimpiartablaButton_2            matlab.ui.control.Button
        NuevafilaButton_2               matlab.ui.control.Button
        ResultadoLabel_2                matlab.ui.control.Label
        AjustelinealButton_2            matlab.ui.control.Button
        LongituddeondanmEditFieldLabel_2  matlab.ui.control.Label
        axes_2                          matlab.ui.control.UIAxes
        PerfildeintensidadyRendijasimpleTab  matlab.ui.container.Tab
        LongituddeondanmEditFieldLabel  matlab.ui.control.Label
        AnchuraderendijammLabel         matlab.ui.control.Label
        TablaDatos                      matlab.ui.control.Table
        Lo                              matlab.ui.control.NumericEditField
        Ar                              matlab.ui.control.NumericEditField
        LimpiartablaButton              matlab.ui.control.Button
        NuevafilaButton                 matlab.ui.control.Button
        ResultadoLabel                  matlab.ui.control.Label
        AjustelinealButton              matlab.ui.control.Button
        PerfildeintensidadButton        matlab.ui.control.Button
        axes                            matlab.ui.control.UIAxes
        EspectroscopaTab                matlab.ui.container.Tab
        p_manual                        matlab.ui.control.NumericEditField
        PrarmetroderedconocidommLabel   matlab.ui.control.Label
        ModoRed                         matlab.ui.control.Switch
        ParmetroderedusadoSwitchLabel   matlab.ui.control.Label
        LimpiartablaButton_3            matlab.ui.control.Button
        NuevafilaButton_3               matlab.ui.control.Button
        longitudes                      matlab.ui.control.Table
        angulos                         matlab.ui.control.Table
        AnalizarespectroButton          matlab.ui.control.Button
        axesEspectro                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        par_red %Definimos p como una propiedad interna para poderlo guardar.
    end
    
    methods (Access = private)

        %Creamos una función a parte que devuelve, de forma simplificada,
        %el color de una cierta longitud de onda: 
        function rgb = wavelengthToRGB(app, wl)

            if wl >= 380 && wl < 440
                rgb = [ (440-wl)/(440-380), 0, 1 ];
            elseif wl >= 440 && wl < 490
                rgb = [ 0, (wl-440)/(490-440), 1 ];
            elseif wl >= 490 && wl < 510
                rgb = [ 0, 1, (510-wl)/(510-490) ];
            elseif wl >= 510 && wl < 580
                rgb = [ (wl-510)/(580-510), 1, 0 ];
            elseif wl >= 580 && wl < 645
                rgb = [ 1, (645-wl)/(645-580), 0 ];
            elseif wl >= 645 && wl <= 750
                rgb = [ 1, 0, 0 ];
            else
                rgb = [0.5 0.5 0.5]; %Gris si está fuera del visible
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Ponemos unas columnas iniciales en las tablas de datos:
           app.TablaDatos.Data = zeros(5, 2);
           app.TablaDatos.ColumnFormat = {'numeric','numeric'};
           app.TablaDatos_2.Data = zeros(5, 2);
           app.TablaDatos_2.ColumnFormat = {'numeric','numeric'};
           app.angulos.ColumnFormat = {'numeric'};
           app.angulos.Data = zeros(5,1);
        end

        % Button pushed function: PerfildeintensidadButton
        function PerfildeintensidadButtonPushed(app, event)
            %Extraemos la longitud de onda y la anchura de la rendija:
            a = app.Ar.Value*1e-3;
            lambda = app.Lo.Value*1e-9;

            %Vector de ángulos
            th = -pi/30:0.0001:pi/30;

            %Calculamos el perfil de intensidad:
            x = (a*sin(th))/lambda;
            I = (sin(pi*x)./(pi*x)).^2;
            I(isnan(I)) = 1; 
            
            %Graficamos el perfil y personalizamos la gráfica:
            plot(app.axes, th, I, 'LineWidth', 1.5)
            xlabel(app.axes, 'Ángulo \theta (rad)')
            ylabel(app.axes, 'Intensidad Normalizada I/I_0')
            title(app.axes, 'Patrón de Difracción de Fraunhofer')
            xlim(app.axes, [min(th) max(th)])
            ylim(app.axes, [0 1.1])
            grid(app.axes, 'on')
        end

        % Button pushed function: AjustelinealButton
        function AjustelinealButtonPushed(app, event)
            %Extraemos los datos de la columna y eliminamos los ceros:
            datosBrutos = app.TablaDatos.Data;
            filasValidas = all(datosBrutos, 2); 
            datosLimpios = datosBrutos(filasValidas, :);
            
            %Comprobaciones de cantidad de puntos y valor de lambda:
            if size(datosLimpios, 1) < 2
                uialert(app.UIFigure, ...
                    'Error: debes introducir al menos dos pares de valores completos en la tabla.', ...
                    'Datos Insuficientes', 'Icon', 'error');
                return;
            end
            if app.Lo.Value <= 0
                uialert(app.UIFigure, ...
                'Por favor, introduce una longitud de onda válida (mayor que 0) antes de simular.', ...
                'Datos Faltantes', ...                                                                  
                'Icon', 'warning');                                                                    
                return;
            end

            %Guardamos los valores a usar:
            D = datosLimpios(:, 1);
            Xmin = datosLimpios(:, 2);
            lambda = app.Lo.Value*1e-6;
            
            %Hacemos el ajuste lineal:
            p = polyfit(D, Xmin, 1);
            m_pendiente = p(1);
            
            %Calculamos el grosor de la rendija:
            dos_a = lambda/m_pendiente;
            
            %Mostramos el resultado:
            app.ResultadoLabel.Text = sprintf('Anchura de la rendija: 2a = %.4f mm', dos_a);
            
            %Graficamos los puntos experimentales y la línea del ajuste:
            plot(app.axes, D, Xmin, 'ko', 'MarkerFaceColor', 'r'); %Puntos
            xlabel(app.axes, 'D(mm)')
            ylabel(app.axes, 'X_{mín}(mm)')
            title(app.axes, 'Ajuste de calibración de rendija')
            hold(app.axes, 'on');
            plot(app.axes, D, polyval(p, D), 'b-', 'LineWidth', 1.5); %Línea
            hold(app.axes, 'off');
            margen = 0.1; %Le damos un margen a la gráfica para que se vea bien el ajuste.
            xlim(app.axes, [min(D)*(1-margen), max(D)*(1+margen)]);
            ylim(app.axes, [min(Xmin)*(1-margen), max(Xmin)*(1+margen)]);
        end

        % Button pushed function: NuevafilaButton
        function NuevafilaButtonPushed(app, event)
            %Botón para añadir una fila a la tabla:
            actual = app.TablaDatos.Data;
            new = [0 0];
            app.TablaDatos.Data = [actual; new];
        end

        % Button pushed function: LimpiartablaButton
        function LimpiartablaButtonPushed(app, event)
            %Botón para limpiar la tabla:
            app.TablaDatos.Data = zeros(5, 2);
        end

        % Button pushed function: AjustelinealButton_2
        function AjustelinealButton_2Pushed(app, event)
            %Botón de ajuste para la red de difracción: es igual que el
            %botón para el ajuste de la rendija, solo cambia la ecuación
            %del cálculo del parámetro de red y que se guarda el valor de
            %este.
            datosBrutos = app.TablaDatos_2.Data;
            filasValidas = all(datosBrutos, 2); 
            datosLimpios = datosBrutos(filasValidas, :);
            
            if size(datosLimpios, 1) < 2
                uialert(app.UIFigure, ...
                    'Error: debes introducir al menos dos pares de valores completos en la tabla.', ...
                    'Datos Insuficientes', 'Icon', 'error');
                return;
            end  
            if app.Lo_2.Value <= 0
                uialert(app.UIFigure, ...
                'Por favor, introduce una longitud de onda válida (mayor que 0) antes de simular.', ...
                'Datos Faltantes', ...                                                                  
                'Icon', 'warning');                                                                    
                return;
            end

            D = datosLimpios(:, 1);
            Xmax = datosLimpios(:, 2);
            lambda = app.Lo_2.Value*1e-6;
            
            A = polyfit(D, Xmax, 1);
            m_pendiente = A(1);
            
            %Cálculo y guardado del parámetro de red:
            p = m_pendiente/lambda;
            app.par_red = p;
            
            app.ResultadoLabel_2.Text = sprintf('Parámetro de red: p = %.4f líneas/mm', p);
            
            plot(app.axes_2, D, Xmax, 'ko', 'MarkerFaceColor', 'r');
            xlabel(app.axes_2, 'D(mm)')
            ylabel(app.axes_2, 'X_{máx}(mm)')
            title(app.axes_2, 'Ajuste de calibración de red')
            hold(app.axes_2, 'on');
            plot(app.axes_2, D, polyval(A, D), 'b-', 'LineWidth', 1.5);
            hold(app.axes_2, 'off');
            margen = 0.1;
            xlim(app.axes_2, [min(D)*(1-margen), max(D)*(1+margen)]);
            ylim(app.axes_2, [min(Xmax)*(1-margen), max(Xmax)*(1+margen)]);
        end

        % Button pushed function: NuevafilaButton_2
        function NuevafilaButton_2Pushed(app, event)
            actual = app.TablaDatos_2.Data;
            new = [0 0];
            app.TablaDatos_2.Data = [actual; new];
        end

        % Button pushed function: LimpiartablaButton_2
        function LimpiartablaButton_2Pushed(app, event)
            app.TablaDatos_2.Data = zeros(5, 2);
        end

        % Button pushed function: AnalizarespectroButton
        function AnalizarespectroButtonPushed(app, event)
            %Si el selector está en "Conocido" se toma el valor introducido
            %manualmente.
            if strcmp(app.ModoRed.Value, 'Conocido')
                if app.p_manual.Value <= 0
                    uialert(app.UIFigure, 'Introduzca un valor de p conocido válido (mayor que 0).', 'Error');
                    return;
                end
                P = app.p_manual.Value;
        
            else
            %Si no, usamos el cálculado mediante el ajuste:
                if isempty(app.par_red) || app.par_red <= 0
                    uialert(app.UIFigure, 'No hay una calibración previa. Usa el modo Manual o calibra en el Módulo "Red de difracción"', 'Falta Calibración');
                    return;
                end
                P = app.par_red;
            end

            %Leemos los ángulos de la tabla, limpiamos los 0 y comprobamos si hay algún valor:
            datos_brutos = app.angulos.Data;
            ang = datos_brutos(any(datos_brutos, 2), :);
            app.angulos.Data = ang;
            ang = ang(~isnan(ang));

            if isempty(ang)
                uialert(app.UIFigure, 'Introduce al menos un ángulo theta en la tabla.', 'Datos vacíos');
                return;
            end
            
            
            %Calculamos las longitudes de onda:
            lambdas = 2*sin(deg2rad(ang))/P*1e6;
            
            %Rellenamos la tabla de resultados:
            app.longitudes.Data = lambdas;
            
            %Representación gráfica del espectro:
            cla(app.axesEspectro);
            hold(app.axesEspectro, 'on');

            %Dibujamos una línea vertical para cada lambda:
            for i = 1:length(lambdas)
                %Usamos la función auxiliar para dibujar colores realistas:
                color_linea = wavelengthToRGB(app,lambdas(i)); 
                xline(app.axesEspectro, lambdas(i), 'Color', color_linea, 'LineWidth', 3);
            end
            
            title(app.axesEspectro, 'Espectro del Gas observado');
            xlabel(app.axesEspectro, '\lambda (nm)');
            app.axesEspectro.XLim = [380 750]; %Fijamos el límite de los ejes al rango visible.
            app.axesEspectro.YTick = []; %Quitamos los números del eje y.
            grid(app.axesEspectro, 'on');
        end

        % Button pushed function: LimpiartablaButton_3
        function LimpiartablaButton_3Pushed(app, event)
            app.angulos.Data = zeros(5,1);
        end

        % Button pushed function: NuevafilaButton_3
        function NuevafilaButton_3Pushed(app, event)
            actual = app.angulos.Data;
            app.angulos.Data = [actual; 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReddedifraccinTab = uitab(app.TabGroup);
            app.ReddedifraccinTab.Title = 'Red de difracción';
            app.axes_2 = uiaxes(app.ReddedifraccinTab);
            xlabel(app.axes_2, 'Eje X')
            ylabel(app.axes_2, 'Eje Y')
            zlabel(app.axes_2, 'Z')
            app.LongituddeondanmEditFieldLabel_2 = uilabel(app.ReddedifraccinTab);
            app.LongituddeondanmEditFieldLabel_2.Text = 'Longitud de onda (nm)';
            app.AjustelinealButton_2 = uibutton(app.ReddedifraccinTab, 'push');
            app.AjustelinealButton_2.ButtonPushedFcn = createCallbackFcn(app, @AjustelinealButton_2Pushed, true);
            app.AjustelinealButton_2.Text = 'Ajuste lineal';
            app.ResultadoLabel_2 = uilabel(app.ReddedifraccinTab);
            app.ResultadoLabel_2.Text = 'Grosor de la rendija calculado';
            app.NuevafilaButton_2 = uibutton(app.ReddedifraccinTab, 'push');
            app.NuevafilaButton_2.ButtonPushedFcn = createCallbackFcn(app, @NuevafilaButton_2Pushed, true);
            app.NuevafilaButton_2.Text = 'Nueva fila';
            app.LimpiartablaButton_2 = uibutton(app.ReddedifraccinTab, 'push');
            app.LimpiartablaButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimpiartablaButton_2Pushed, true);
            app.LimpiartablaButton_2.Text = 'Limpiar tabla';
            app.Lo_2 = uieditfield(app.ReddedifraccinTab, 'numeric');
            app.TablaDatos_2 = uitable(app.ReddedifraccinTab);
            app.TablaDatos_2.ColumnName = {'Distancia(mm)'; 'Xₘₐₓ(mm)'};
            app.TablaDatos_2.RowName = {};
            app.TablaDatos_2.ColumnEditable = true;
            app.PerfildeintensidadyRendijasimpleTab = uitab(app.TabGroup);
            app.PerfildeintensidadyRendijasimpleTab.Title = 'Perfil de intensidad y Rendija simple';
            app.axes = uiaxes(app.PerfildeintensidadyRendijasimpleTab);
            xlabel(app.axes, 'Eje X')
            ylabel(app.axes, 'Eje Y')
            zlabel(app.axes, 'Z')
            app.PerfildeintensidadButton = uibutton(app.PerfildeintensidadyRendijasimpleTab, 'push');
            app.PerfildeintensidadButton.ButtonPushedFcn = createCallbackFcn(app, @PerfildeintensidadButtonPushed, true);
            app.PerfildeintensidadButton.Text = 'Perfil de intensidad';
            app.AjustelinealButton = uibutton(app.PerfildeintensidadyRendijasimpleTab, 'push');
            app.AjustelinealButton.ButtonPushedFcn = createCallbackFcn(app, @AjustelinealButtonPushed, true);
            app.AjustelinealButton.Text = 'Ajuste lineal';
            app.ResultadoLabel = uilabel(app.PerfildeintensidadyRendijasimpleTab);
            app.ResultadoLabel.Text = 'Grosor de la rendija calculado';
            app.NuevafilaButton = uibutton(app.PerfildeintensidadyRendijasimpleTab, 'push');
            app.NuevafilaButton.ButtonPushedFcn = createCallbackFcn(app, @NuevafilaButtonPushed, true);
            app.NuevafilaButton.Text = 'Nueva fila';
            app.LimpiartablaButton = uibutton(app.PerfildeintensidadyRendijasimpleTab, 'push');
            app.LimpiartablaButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiartablaButtonPushed, true);
            app.LimpiartablaButton.Text = 'Limpiar tabla';
            app.Ar = uieditfield(app.PerfildeintensidadyRendijasimpleTab, 'numeric');
            app.Lo = uieditfield(app.PerfildeintensidadyRendijasimpleTab, 'numeric');
            app.TablaDatos = uitable(app.PerfildeintensidadyRendijasimpleTab);
            app.TablaDatos.ColumnName = {'Distancia(mm)'; 'Xₘᵢₙ(mm)'};
            app.TablaDatos.RowName = {};
            app.TablaDatos.ColumnEditable = true;
            app.AnchuraderendijammLabel = uilabel(app.PerfildeintensidadyRendijasimpleTab);
            app.AnchuraderendijammLabel.Text = 'Anchura de rendija (mm)';
            app.LongituddeondanmEditFieldLabel = uilabel(app.PerfildeintensidadyRendijasimpleTab);
            app.LongituddeondanmEditFieldLabel.Text = 'Longitud de onda (nm)';
            app.EspectroscopaTab = uitab(app.TabGroup);
            app.EspectroscopaTab.Title = 'Espectroscopía';
            app.axesEspectro = uiaxes(app.EspectroscopaTab);
            title(app.axesEspectro, 'Título')
            xlabel(app.axesEspectro, 'Eje X')
            ylabel(app.axesEspectro, 'Eje Y')
            zlabel(app.axesEspectro, 'Z')
            app.AnalizarespectroButton = uibutton(app.EspectroscopaTab, 'push');
            app.AnalizarespectroButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarespectroButtonPushed, true);
            app.AnalizarespectroButton.Text = 'Analizar espectro';
            app.angulos = uitable(app.EspectroscopaTab);
            app.angulos.ColumnName = {'Ángulos  medidos (º)'};
            app.angulos.RowName = {};
            app.angulos.ColumnEditable = true;
            app.longitudes = uitable(app.EspectroscopaTab);
            app.longitudes.ColumnName = {'Longitudes de onda resultantes (nm)'};
            app.longitudes.RowName = {};
            app.longitudes.ColumnEditable = true;
            app.NuevafilaButton_3 = uibutton(app.EspectroscopaTab, 'push');
            app.NuevafilaButton_3.ButtonPushedFcn = createCallbackFcn(app, @NuevafilaButton_3Pushed, true);
            app.NuevafilaButton_3.Text = 'Nueva fila';
            app.LimpiartablaButton_3 = uibutton(app.EspectroscopaTab, 'push');
            app.LimpiartablaButton_3.ButtonPushedFcn = createCallbackFcn(app, @LimpiartablaButton_3Pushed, true);
            app.LimpiartablaButton_3.Text = 'Limpiar tabla';
            app.ParmetroderedusadoSwitchLabel = uilabel(app.EspectroscopaTab);
            app.ParmetroderedusadoSwitchLabel.Text = 'Parámetro de red usado';
            app.ModoRed = uiswitch(app.EspectroscopaTab, 'slider');
            app.ModoRed.Items = {'Conocido', 'Experimental'};
            app.ModoRed.Value = 'Conocido';
            app.PrarmetroderedconocidommLabel = uilabel(app.EspectroscopaTab);
            app.PrarmetroderedconocidommLabel.Text = 'Prarámetro de red conocido (lín/mm)';
            app.p_manual = uieditfield(app.EspectroscopaTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = difraccion_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end