classdef polarizacion_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlayButton                matlab.ui.control.Button
        ParmetrosdeStokesVmPanel  matlab.ui.container.Panel
        S3EditField               matlab.ui.control.NumericEditField
        S3EditFieldLabel          matlab.ui.control.Label
        S2EditField               matlab.ui.control.NumericEditField
        S2EditFieldLabel          matlab.ui.control.Label
        S1EditField               matlab.ui.control.NumericEditField
        S1EditFieldLabel          matlab.ui.control.Label
        S0EditField               matlab.ui.control.NumericEditField
        S0EditFieldLabel          matlab.ui.control.Label
        DeltaSlider               matlab.ui.control.Slider
        DesfaseSliderLabel        matlab.ui.control.Label
        EySlider                  matlab.ui.control.Slider
        AmplitudE0ySliderLabel    matlab.ui.control.Label
        ExSlider                  matlab.ui.control.Slider
        AmplitudE0xSliderLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Definimos los parámetros generales:
        t
        Ex
        Ey
        hPoint
    end
    
    methods (Access = private)
        %Definimos una función a parte para graficar la tratectoria:
        function updateSimulation(app) 
            %Obtenemos los valores de los sliders:
            E0x = app.ExSlider.Value;
            E0y = app.EySlider.Value;
            delta = app.DeltaSlider.Value;
        
            %Calculamos los parámetros de stokes:
            app.S0EditField.Value = E0x^2+E0y^2;
            app.S1EditField.Value = E0x^2-E0y^2;
            app.S2EditField.Value = 2*E0x*E0y*cos(delta);
            app.S3EditField.Value = 2*E0x*E0y*sin(delta);
        
            %Creamos un vector de tiempos para graficar las posiciones de E
            %a lo largo de un ciclo:
            app.t = linspace(0, 2*pi, 100); 
            app.Ex = E0x*cos(app.t);
            app.Ey = E0y*cos(app.t-delta);
            
            %Graficamos la trayectoria:
            plot(app.UIAxes, app.Ex, app.Ey, 'Color', [0.7 0.7 0.7], 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');

            %Colocamos el punto en su posición inicial (t=0):
            app.hPoint = plot(app.UIAxes, app.Ex(1), app.Ey(1), 'ro', ...
                         'MarkerFaceColor', 'r', 'MarkerSize', 10);
            
            hold(app.UIAxes, 'off');
            
            %Fijamos el tamaño de los ejes en un cuadrado:
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-1.1 1.1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Colacamos las marcas para el slider del desfase:
            app.DeltaSlider.MajorTicks = [0, pi/4, pi/2, 3*pi/4, pi, 5*pi/4, 3*pi/2, 7*pi/4, 2*pi];
            app.DeltaSlider.MajorTickLabels = {'0', 'π/4', 'π/2', '3π/4', 'π', '5π/4', '3π/2', '7π/4', '2π'};

            %Títulos de la gráfica:
            title(app.UIAxes, 'Trayectoria del Vector Campo Eléctrico');
            xlabel(app.UIAxes, 'E_{x} (V/m)');
            ylabel(app.UIAxes, 'E_{y} (V/m)');

            %Forzamos que los ejes tengan el mismo tamaño:
            axis(app.UIAxes, 'equal'); 
            
            %Límites fijos y marcas de los ejes:
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-1.1 1.1];
            app.UIAxes.XTick = [-1.0, -0.75, -0.5, -0.25, 0, 0.25, 0.5, 0.75, 1];
            app.UIAxes.YTick = [-1.0, -0.75, -0.5, -0.25, 0, 0.25, 0.5, 0.75, 1];
            
            %Malla y líneas centrales en la gráfica:
            grid(app.UIAxes, 'on');
            xline(app.UIAxes, 0, 'k--', 'HandleVisibility', 'off'); % Eje vertical en el centro
            yline(app.UIAxes, 0, 'k--', 'HandleVisibility', 'off'); % Eje horizontal en el centro
        end

        % Value changed function: DeltaSlider
        function DeltaSliderValueChanged(app, event)
            updateSimulation(app);
            %Al mover cualquier cualquier slider se actualiza la gráfica.
        end

        % Value changed function: EySlider
        function EySliderValueChanged(app, event)
            updateSimulation(app);
            
        end

        % Value changed function: ExSlider
        function ExSliderValueChanged(app, event)
            updateSimulation(app);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %Animación del movimiento de la punta del vector E:
            for vueltas = 1:10 %El punto dará 10 vueltas.

                for k = 1:length(app.t) %Recorremos el vector de tiempos.

                    if isvalid(app.hPoint) %Verificamos que el punto sea válido antes de moverlo.
                        %Actualizamos la posición del punto rojo:
                        app.hPoint.XData = app.Ex(k);
                        app.hPoint.YData = app.Ey(k);
                        
                        %Refrescamos la posición:
                        drawnow limitrate;
                        
                        %Pausa para controlar la velocidad:
                        pause(0.03); 
                    else
                        break;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plano de Polarización (Vista Transversal)')
            xlabel(app.UIAxes, 'Componente Ex')
            ylabel(app.UIAxes, 'Componente Ey')
            zlabel(app.UIAxes, 'Z')
            app.AmplitudE0xSliderLabel = uilabel(app.UIFigure);
            app.AmplitudE0xSliderLabel.Text = 'Amplitud E0x';
            app.ExSlider = uislider(app.UIFigure);
            app.ExSlider.Limits = [0 1];
            app.ExSlider.ValueChangedFcn = createCallbackFcn(app, @ExSliderValueChanged, true);
            app.AmplitudE0ySliderLabel = uilabel(app.UIFigure);
            app.AmplitudE0ySliderLabel.Text = 'Amplitud E0y';
            app.EySlider = uislider(app.UIFigure);
            app.EySlider.Limits = [0 1];
            app.EySlider.ValueChangedFcn = createCallbackFcn(app, @EySliderValueChanged, true);
            app.DesfaseSliderLabel = uilabel(app.UIFigure);
            app.DesfaseSliderLabel.Text = 'Desfase';
            app.DeltaSlider = uislider(app.UIFigure);
            app.DeltaSlider.Limits = [0 6.28318530717959];
            app.DeltaSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaSliderValueChanged, true);
            app.ParmetrosdeStokesVmPanel = uipanel(app.UIFigure);
            app.ParmetrosdeStokesVmPanel.Title = 'Parámetros de Stokes (V/m)²';
            app.S0EditFieldLabel = uilabel(app.ParmetrosdeStokesVmPanel);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.ParmetrosdeStokesVmPanel, 'numeric');
            app.S0EditField.ValueDisplayFormat = '%.5f';
            app.S1EditFieldLabel = uilabel(app.ParmetrosdeStokesVmPanel);
            app.S1EditFieldLabel.Text = 'S1';
            app.S1EditField = uieditfield(app.ParmetrosdeStokesVmPanel, 'numeric');
            app.S1EditField.ValueDisplayFormat = '%.5f';
            app.S2EditFieldLabel = uilabel(app.ParmetrosdeStokesVmPanel);
            app.S2EditFieldLabel.Text = 'S2';
            app.S2EditField = uieditfield(app.ParmetrosdeStokesVmPanel, 'numeric');
            app.S2EditField.ValueDisplayFormat = '%.5f';
            app.S3EditFieldLabel = uilabel(app.ParmetrosdeStokesVmPanel);
            app.S3EditFieldLabel.Text = 'S3';
            app.S3EditField = uieditfield(app.ParmetrosdeStokesVmPanel, 'numeric');
            app.S3EditField.ValueDisplayFormat = '%.5f';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = polarizacion_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end