classdef balmer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_n                     matlab.ui.control.NumericEditField
        NivelfinalnEditFieldLabel       matlab.ui.control.Label
        EditField_m                     matlab.ui.control.NumericEditField
        NivelinicialmEditFieldLabel     matlab.ui.control.Label
        ColorPanel                      matlab.ui.container.Panel
        EnergiaEditField                matlab.ui.control.NumericEditField
        EnergaEeVEditFieldLabel         matlab.ui.control.Label
        FrecuenciaEditField             matlab.ui.control.NumericEditField
        FrecuenciaHzLabel               matlab.ui.control.Label
        LongituddeondaEditField         matlab.ui.control.NumericEditField
        LongituddeondanmLabel           matlab.ui.control.Label
        SerieEditField                  matlab.ui.control.EditField
        SerieEditFieldLabel             matlab.ui.control.Label
        RegionEditField                 matlab.ui.control.EditField
        RegindelespectroEditFieldLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
    end

    
    methods (Access = private)
        %Creamos una función auxiliar para mostrar el color:
        function actualizarColor(app, nm)
            if nm>=380 && nm<=450
                col = [0.5 0 1]; %Violeta
            elseif nm>450 && nm<=495
                col = [0 0 1];   %Azul
            elseif nm>495 && nm<=570
                col = [0 1 0];   %Verde
            elseif nm>570 && nm<=590
                col = [1 1 0];   %Amarillo
            elseif nm>590 && nm<=620
                col = [1 0.5 0]; %Naranja
            elseif nm>620 && nm<=750
                col = [1 0 0];   %Rojo
            else
                col = [0.8 0.8 0.8]; %Gris (fuera del visible)
            end
            app.ColorPanel.BackgroundColor = col;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %Extraemos los valores introducidos de n y m:
            m = app.EditField_m.Value;
            n = app.EditField_n.Value;
        
            %Comprabamos que ambos sean valores enteros y que m sea mayor
            %que n:
            if m<=n || n<=0 || mod(m,1)~=0 || mod(n,1)~=0
                uialert(app.UIFigure, 'm debe ser mayor que n y ambos enteros positivos.', 'Error de entrada');
                return;
            end
        
            %Definimos la constantes físicas necesarias en los cálculos:
            Rh = 1.097373e7; %Constante de Rydberg (m^-1)
            c = 2.99792458e8; %Velocidad de la luz (m/s)
            h_ev = 4.135667e-15; %Constante de Planck (eV·s)
        
            %Cálculamos los diferentes valores requeridos:
            inv_lambda = Rh * (1/n^2 - 1/m^2);
            lambda_m = 1 / inv_lambda;
            lambda_nm = lambda_m * 1e9;
            frecuencia = c / lambda_m;
            energia_ev = h_ev * frecuencia;
        
            %Mostramos por pantalla los resultados:
            app.LongituddeondaEditField.Value = lambda_nm;
            app.FrecuenciaEditField.Value =  frecuencia;
            app.EnergiaEditField.Value =  energia_ev;
        
            %Clasificamos en que serie y región se encuentra la luz:
            series = {"Lyman", "Balmer", "Paschen", "Brackett", "Pfund"};
            if n<=5
                app.SerieEditField.Value = series{n};

            else
                app.SerieEditField = num2str(n);
            end
        
            if lambda_nm<10
                app.RegionEditField.Value = "Rayos X / UV Extremo";

            elseif lambda_nm>=10 && lambda_nm<390
                app.RegionEditField.Value = "Ultravioleta (UV)";

            elseif lambda_nm>=390 && lambda_nm<=750
                app.RegionEditField.Value = "Espectro Visible";

            elseif lambda_nm>750 && lambda_nm<=1000000 
                app.RegionEditField.Value = "Infrarrojo (IR)";

            else
                app.RegionEditField.Value = "Microondas / Radio";

            end
        
            %Llamamos a la función auxiliar para poner el color:
            actualizarColor(app, lambda_nm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.RegindelespectroEditFieldLabel = uilabel(app.UIFigure);
            app.RegindelespectroEditFieldLabel.Text = 'Región del espectro';
            app.RegionEditField = uieditfield(app.UIFigure, 'text');
            app.SerieEditFieldLabel = uilabel(app.UIFigure);
            app.SerieEditFieldLabel.Text = 'Serie';
            app.SerieEditField = uieditfield(app.UIFigure, 'text');
            app.LongituddeondanmLabel = uilabel(app.UIFigure);
            app.LongituddeondanmLabel.Text = 'Longitud de onda λ (nm)';
            app.LongituddeondaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongituddeondaEditField.ValueDisplayFormat = '%.3f';
            app.FrecuenciaHzLabel = uilabel(app.UIFigure);
            app.FrecuenciaHzLabel.Text = 'Frecuencia ν (Hz)';
            app.FrecuenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaEditField.ValueDisplayFormat = '%.3e';
            app.EnergaEeVEditFieldLabel = uilabel(app.UIFigure);
            app.EnergaEeVEditFieldLabel.Text = 'Energía ΔE (eV)';
            app.EnergiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergiaEditField.ValueDisplayFormat = '%.3f';
            app.ColorPanel = uipanel(app.UIFigure);
            app.ColorPanel.Title = 'COLOR';
            app.NivelinicialmEditFieldLabel = uilabel(app.UIFigure);
            app.NivelinicialmEditFieldLabel.Text = 'Nivel inicial (m)';
            app.EditField_m = uieditfield(app.UIFigure, 'numeric');
            app.NivelfinalnEditFieldLabel = uilabel(app.UIFigure);
            app.NivelfinalnEditFieldLabel.Text = 'Nivel final (n)';
            app.EditField_n = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balmer_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end